classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TrialsFileLabel           matlab.ui.control.Label
        BlockSpinner              matlab.ui.control.Spinner
        BlockSpinnerLabel         matlab.ui.control.Label
        TrialsLabel               matlab.ui.control.Label
        SelectTrialsButton        matlab.ui.control.Button
        LocationDropDown          matlab.ui.control.DropDown
        LocationDropDownLabel     matlab.ui.control.Label
        ThresholdCheckBox         matlab.ui.control.CheckBox
        TestimagetypeButtonGroup  matlab.ui.container.ButtonGroup
        GaborButton               matlab.ui.control.RadioButton
        ImageButton               matlab.ui.control.RadioButton
        SubjectIDEditField        matlab.ui.control.EditField
        SubjectIDEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        pathDataRoot % root folder for input/output data
        pathImgRoot % root folder for imageset
        isTrialsChosen % set to true when trials are chosen
        isImagesChosen % set to true when images selected
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ethDataRoot, ethImgRoot)

            app.pathDataRoot = ethDataRoot;
            app.pathImgRoot = ethImgRoot;
            app.BlockSpinner.Editable = false;
            app.TrialsFileLabel.Text = "No trials selected";

        end

        % Button pushed function: SelectTrialsButton
        function LoadTrialsPushed(app, event)
            [file,path] = uigetfile('*.mat','Select trials file',app.pathDataRoot);  %open a mat file

            % check input file for 'blocks' - if so, enable block
            % selection.
            Y = load(fullfile(path, file));
            if any(contains(fieldnames(Y), 'blocks'))
                numBlocks = length(Y.blocks);
                app.BlockSpinner.Editable = true;
                app.BlockSpinner.Limits = [1,numBlocks];
                app.BlockSpinner.Value = 1;
                app.TrialsFileLabel.Text = file;
            else
                app.BlockSpinner.Editable = false;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.TestimagetypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TestimagetypeButtonGroup.Title = 'Test image type';
            app.ImageButton = uiradiobutton(app.TestimagetypeButtonGroup);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.GaborButton = uiradiobutton(app.TestimagetypeButtonGroup);
            app.GaborButton.Text = 'Gabor';
            app.ThresholdCheckBox = uicheckbox(app.UIFigure);
            app.ThresholdCheckBox.Text = 'Threshold?';
            app.LocationDropDownLabel = uilabel(app.UIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.UIFigure);
            app.LocationDropDown.Items = {'Booth (test)', 'Booth (subj)', 'Desk'};
            app.LocationDropDown.Value = 'Booth (test)';
            app.SelectTrialsButton = uibutton(app.UIFigure, 'push');
            app.SelectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrialsPushed, true);
            app.SelectTrialsButton.Text = 'Select Trials';
            app.TrialsLabel = uilabel(app.UIFigure);
            app.TrialsLabel.Text = 'Trials:';
            app.BlockSpinnerLabel = uilabel(app.UIFigure);
            app.BlockSpinnerLabel.Text = 'Block#';
            app.BlockSpinner = uispinner(app.UIFigure);
            app.TrialsFileLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end