classdef eth2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImagesetPanel                 matlab.ui.container.Panel
        ExitButton                    matlab.ui.control.Button
        InterTrialIntervalsEditField  matlab.ui.control.NumericEditField
        InterTrialIntervalsEditFieldLabel  matlab.ui.control.Label
        ResponsetimesEditField        matlab.ui.control.EditField
        ResponsetimesEditFieldLabel   matlab.ui.control.Label
        GaptimesEditField             matlab.ui.control.EditField
        GaptimesEditFieldLabel        matlab.ui.control.Label
        SampletimesEditField          matlab.ui.control.EditField
        SampletimesEditFieldLabel     matlab.ui.control.Label
        FixBreaktimesEditField        matlab.ui.control.EditField
        FixBreaktimesEditFieldLabel   matlab.ui.control.Label
        FixationtimesEditField        matlab.ui.control.EditField
        FixationtimesEditFieldLabel   matlab.ui.control.Label
        MaxAcqtimesEditField          matlab.ui.control.EditField
        MaxAcqtimesEditFieldLabel     matlab.ui.control.Label
        GenerateTrialsButton          matlab.ui.control.Button
        LRNcountsEditField            matlab.ui.control.EditField
        LRNcountsLabel                matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        SelectFolderButton            matlab.ui.control.Button
        FolderSelectedLabel           matlab.ui.control.Label
        RootFolderLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        Imageset % Imageset to be used 
        LRNcounts % 3xN; row 1,2,3 = LeftChg, NoChg, RightChg
        Blocks % blocks of trials
        MaxAcqTimeSec % max acquisition time
        FixTimeSec % Fixation time
        BreakTimeSec % punish time 
        SampTimeSec
        GapTimeSec
        RespTimeSec
        ITISec
    end
    
    methods (Access = private)
        
        function myInitSinglePref(~, group, name, value)
            if ~ispref(group, name)
                addpref(group, name, value);
            end
        end
        
        function myInitPrefs(app)
            app.myInitSinglePref('etholog', 'FolderSelected', pwd());
            app.myInitSinglePref('etholog', 'LRNcounts', [30, 20, 10; 10, 30, 20; 20, 20, 20]');
            app.myInitSinglePref('etholog', 'MaxAcqTimeSec', 2.0);
            app.myInitSinglePref('etholog', 'FixTimeSec', [0.5, 1.0]);
            app.myInitSinglePref('etholog', 'BreakTimeSec', 0.25);
            app.myInitSinglePref('etholog', 'SampTimeSec', 1.5);
            app.myInitSinglePref('etholog', 'GapTimeSec', 0.0);
            app.myInitSinglePref('etholog', 'RespTimeSec', 2.0);
            app.myInitSinglePref('etholog', 'ITISec', 0.5);
        end
        
        function ok = validateInputs(app)
            try
                app.MaxAcqTimeSec = eval(app.MaxAcqtimesEditField.Value);
                app.FixTimeSec = eval(app.FixationtimesEditField.Value);
                app.BreakTimeSec = eval(app.FixBreaktimesEditField.Value);
                app.SampTimeSec = eval(app.SampletimesEditField.Value);
                app.GapTimeSec = eval(app.GaptimesEditField.Value);
                app.RespTimeSec = eval(app.ResponsetimesEditField.Value);
                app.ITISec = app.InterTrialIntervalsEditField.Value;
                ok = isa(app.Imageset, 'imageset');
            catch ME
                fprintf('Error validating inputs. Matlab error: \n%s\n', ME.getReport());
                ok = 0;
            end
        end
        
        function myUpdatePrefs(app)
            setpref('etholog', 'FolderSelected', app.FolderSelectedLabel.Text);
            setpref('etholog', 'LRNcounts', app.LRNcounts);
            setpref('etholog', 'MaxAcqTimeSec', app.MaxAcqTimeSec);
            setpref('etholog', 'FixTimeSec', app.FixTimeSec);
            setpref('etholog', 'BreakTimeSec', app.BreakTimeSec);
            setpref('etholog', 'SampTimeSec', app.SampTimeSec);
            setpref('etholog', 'GapTimeSec', app.GapTimeSec);
            setpref('etholog', 'RespTimeSec', app.RespTimeSec);
            setpref('etholog', 'ITISec', app.ITISec);
        end
        
        function ok = maybeSaveAndExit(app)
            ok = true;  % default is to exit
            if isa(app.Imageset, 'imageset') && length(app.Blocks) > 0
                opts={'Cancel', 'Don''t Save', 'Save&Exit'};
                answer = uiconfirm(app.UIFigure, 'Save trials and imageset?', 'Confirm Save', 'Options', opts, 'CancelOption', 1, 'DefaultOption', 3);
                ianswer = strmatch(answer, opts);
                switch ianswer
                    case 1
                        % Cancel
                        ok = false;
                    case 2
                        ok = true;
                    case 3
                        blocks = app.Blocks;
                        img = app.Imageset;

                        save('eth2.mat', 'img', 'blocks');
                        ok = true;
                end
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Imageset = [];
            app.Blocks = {};
            app.FolderSelectedLabel.Text = 'none selected';
            app.StatusLabel.Text='';
            app.GenerateTrialsButton.Enable = "off";

            % init prefs
            app.myInitPrefs();
            
            % retrieve values for dialog
            app.FolderSelectedLabel.Text = getpref('etholog', 'FolderSelected');
            
            app.LRNcounts = getpref('etholog', 'LRNcounts');
            app.LRNcountsEditField.Value = mat2str(app.LRNcounts);

            app.MaxAcqTimeSec = getpref('etholog', 'MaxAcqTimeSec');
            app.MaxAcqtimesEditField.Value = mat2str(app.MaxAcqTimeSec);

            app.FixTimeSec = getpref('etholog', 'FixTimeSec');
            app.FixationtimesEditField.Value = mat2str(app.FixTimeSec);

            app.BreakTimeSec = getpref('etholog', 'BreakTimeSec');
            app.FixBreaktimesEditField.Value = mat2str(app.BreakTimeSec);
            
            app.SampTimeSec = getpref('etholog', 'SampTimeSec');
            app.SampletimesEditField.Value = mat2str(app.SampTimeSec);
            
            app.GapTimeSec = getpref('etholog', 'GapTimeSec');
            app.GaptimesEditField.Value = mat2str(app.GapTimeSec);

            app.RespTimeSec = getpref('etholog', 'RespTimeSec');
            app.ResponsetimesEditField.Value = mat2str(app.RespTimeSec);

            app.InterTrialIntervalsEditField.Value = getpref('etholog', 'ITISec');
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Select folder, then load
            selected = uigetdir(app.FolderSelectedLabel.Text, 'Select Imageset root folder');
            if ischar(selected)
                try
                    app.Imageset = imageset(selected);
                    app.FolderSelectedLabel.Text = selected;
                    app.StatusLabel.Text = sprintf('%d images loaded.', length(app.Imageset.Images));
                    app.Blocks = {};    % clear out any old blocks that had been generated.
                    if app.validateInputs()
                        app.GenerateTrialsButton.Enable = "on";
                    else
                        app.GenerateTrialsButton.Enable = "off";
                    end                        
                catch ME
                    fprintf('Error reading imageset from %s:\n%s\n', selected, getReport(ME));
                end 
            else
                fprintf('nothing selected\n');
            end

        end

        % Value changed function: LRNcountsEditField
        function LRNcountsEditFieldValueChanged(app, event)
            value = app.LRNcountsEditField.Value;
            try
                Z = eval(value);
                if size(Z, 1) ~= 3
                    errordlg('LRN counts must eval to a 3xN array. Columns should sum to same value.', 'Input Error');
                else
                    zz=sum(Z);
                    if ~all(zz==zz(1))
                        errordlg('LRN counts must eval to a 3xN array. Columns should sum to same value.', 'Input Error');
                    end
                end
                app.LRNcounts = Z;
                app.StatusLabel.Text = sprintf('%d blocks, %d images per block.', size(Z, 2), sum(Z(:,1)));
            catch ME
                errordlg(ME.message, 'Eval error');
            end

            if app.validateInputs()
                app.GenerateTrialsButton.Enable = "on";
            else
                app.GenerateTrialsButton.Enable = "off";
            end                        

        end

        % Button pushed function: GenerateTrialsButton
        function GenerateTrialsButtonPushed(app, event)
            try
                blocks = generateEthBlocks(app.Imageset.BalancedFileKeys, app.LRNcounts, ...
                    'FixationTime', app.FixTimeSec, ...
                    'MaxAcquisitionTime', app.MaxAcqTimeSec, ...
                    'FixationBreakEarlyTime', app.BreakTimeSec, ...
                    'FixationBreakLateTime', app.BreakTimeSec, ...
                    'SampTime', app.SampTimeSec, ...
                    'GapTime', app.GapTimeSec, ...
                    'RespTime', app.RespTimeSec);
                app.StatusLabel.Text = sprintf('%d blocks created.', length(blocks));
                app.Blocks = blocks;
            catch ME
                uierror(app.UIFigure,  ME.getReport());
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % save stuff for preferences
            app.myUpdatePrefs();

            ok = app.maybeSaveAndExit();
            if ok
                delete(app);
            end
            
        end

        % Value changed function: FixBreaktimesEditField, 
        % ...and 5 other components
        function TimingEditFieldValueChanged(app, event)
            value = event.Source.Value;
            try
                A = eval(value);
                if ~isnumeric(A) || length(A)>2
                    uialert(app.UIFigure, {'This value must be a single value (time in sec) or a two-element range of times, e.g. [1.0, 1.5].'}, 'Input error');
                    event.Source.Value = event.PreviousValue;
                end
            catch ME
                uialert(app.UIFigure, ...
                    {'This value must be a single value (time in sec) or a two-element range of times, e.g. [1.0, 1.5].', ...
                    'Matlab error:', ME.getReport}, 'Input error');
                event.Source.Value = event.PreviousValue;
            end                
            if app.validateInputs()
                app.GenerateTrialsButton.Enable = "on";
            else
                app.GenerateTrialsButton.Enable = "off";
            end                        

        end

        % Value changed function: InterTrialIntervalsEditField
        function InterTrialIntervalsEditFieldValueChanged(app, event)
            app.ITISec = app.InterTrialIntervalsEditField.Value;
            if app.validateInputs()
                app.GenerateTrialsButton.Enable = "on";
            else
                app.GenerateTrialsButton.Enable = "off";
            end                        

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            if app.maybeSaveAndExit()
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImagesetPanel = uipanel(app.UIFigure);
            app.ImagesetPanel.Title = 'Imageset';
            app.RootFolderLabel = uilabel(app.ImagesetPanel);
            app.RootFolderLabel.Text = 'Root Folder:';
            app.FolderSelectedLabel = uilabel(app.ImagesetPanel);
            app.SelectFolderButton = uibutton(app.ImagesetPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.StatusLabel = uilabel(app.ImagesetPanel);
            app.StatusLabel.Text = 'Label2';
            app.LRNcountsLabel = uilabel(app.ImagesetPanel);
            app.LRNcountsLabel.Text = 'L-R-N counts';
            app.LRNcountsEditField = uieditfield(app.ImagesetPanel, 'text');
            app.LRNcountsEditField.ValueChangedFcn = createCallbackFcn(app, @LRNcountsEditFieldValueChanged, true);
            app.GenerateTrialsButton = uibutton(app.ImagesetPanel, 'push');
            app.GenerateTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrialsButtonPushed, true);
            app.GenerateTrialsButton.Text = 'Generate Trials';
            app.MaxAcqtimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.MaxAcqtimesEditFieldLabel.Text = 'Max Acq time (s)';
            app.MaxAcqtimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.MaxAcqtimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimingEditFieldValueChanged, true);
            app.FixationtimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.FixationtimesEditFieldLabel.Text = 'Fixation time (s)';
            app.FixationtimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.FixationtimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimingEditFieldValueChanged, true);
            app.FixBreaktimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.FixBreaktimesEditFieldLabel.Text = 'Fix Break time (s)';
            app.FixBreaktimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.FixBreaktimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimingEditFieldValueChanged, true);
            app.SampletimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.SampletimesEditFieldLabel.Text = 'Sample time (s)';
            app.SampletimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.SampletimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimingEditFieldValueChanged, true);
            app.GaptimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.GaptimesEditFieldLabel.Text = 'Gap time (s)';
            app.GaptimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.GaptimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimingEditFieldValueChanged, true);
            app.ResponsetimesEditFieldLabel = uilabel(app.ImagesetPanel);
            app.ResponsetimesEditFieldLabel.Text = 'Response time (s)';
            app.ResponsetimesEditField = uieditfield(app.ImagesetPanel, 'text');
            app.ResponsetimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimingEditFieldValueChanged, true);
            app.InterTrialIntervalsEditFieldLabel = uilabel(app.ImagesetPanel);
            app.InterTrialIntervalsEditFieldLabel.Text = 'Inter-Trial Interval (s)';
            app.InterTrialIntervalsEditField = uieditfield(app.ImagesetPanel, 'numeric');
            app.InterTrialIntervalsEditField.Limits = [0 Inf];
            app.InterTrialIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @InterTrialIntervalsEditFieldValueChanged, true);
            app.ExitButton = uibutton(app.ImagesetPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eth2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end