classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        SelectImagesetButton     matlab.ui.control.Button
        ImagesetNameLabel        matlab.ui.control.Label
        ImagesetLabel            matlab.ui.control.Label
        BlockSelectedLabel       matlab.ui.control.Label
        LocationDropDown         matlab.ui.control.DropDown
        LocationLabel            matlab.ui.control.Label
        SubjectIDEditField       matlab.ui.control.EditField
        SubjectIDEditFieldLabel  matlab.ui.control.Label
        BlockLabel               matlab.ui.control.Label
        TrialsFileLabel          matlab.ui.control.Label
        TrialsLabel              matlab.ui.control.Label
        SelectTrialsButton       matlab.ui.control.Button
        ThresholdCheckBox        matlab.ui.control.CheckBox
        TesttypeButtonGroup      matlab.ui.container.ButtonGroup
        GaborButton              matlab.ui.control.RadioButton
        ImageButton              matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        pathDataRoot % root folder for input/output data
        pathImgRoot % root folder for imageset
        isFileSelected % set to true when trial/blocks file is chosen
        filePath % path of trials/blocks file
        fileName % basename of trials/blocks file
        fileNBlocks % number of blocks in trials file
        fileBlockIndex % which block in file, or 0 if none selected
        isImagesetSelected % set to true when images selected
        imagesetName % Name of imageset to use
        imagesetParamsFunc % Paramsfunc to use when loading imageset
    end
    
    methods (Access = private)
        
        function updateFileBlocks(app)
            if app.isFileSelected
                app.TrialsFileLabel.Text = app.fileName;
                if app.fileNBlocks > 0
                    if app.fileBlockIndex <= app.fileNBlocks
                        app.BlockSelectedLabel.Text = sprintf('%d/%d', app.fileBlockIndex, app.fileNBlocks);
                    else
                        app.BlockSelectedLabel.Text = 'Not selected';
                    end
                else
                    app.BlockSelectedLabel.Text = 'N/A';
                end
            else
                app.TrialsFileLabel.Text = 'Not selected';
                app.BlockSelectedLabel.Text = 'N/A';
            end

            if app.isImagesetSelected
                app.ImagesetNameLabel.Text = sprintf('%s [%s]', app.imagesetName, app.imagesetParamsFunc);
            else
                app.ImagesetNameLabel.Text = 'None selected';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ethDataRoot, ethImgRoot)

            app.pathDataRoot = ethDataRoot;
            app.pathImgRoot = ethImgRoot;
            app.isFileSelected = false;
            app.isImagesetSelected = false;
            app.imagesetName = '';
            app.imagesetParamsFunc = '';
            app.fileBlockIndex = -1;
            app.fileNBlocks = -1;
            updateFileBlocks(app);

        end

        % Button pushed function: SelectTrialsButton
        function LoadTrialsPushed(app, event)
            if app.ImageButton.Value
                ttype = 'mimg';
            elseif app.GaborButton.Value
                ttype = 'gab';
            else
                % should not happen with radio buttons
                ttype = 'unk';
            end
            if app.ThresholdCheckBox.Value
                etype = 'thr';
            else
                etype = 'exp';
            end
            pathInitial = fullfile(app.pathDataRoot, 'input');
            filterFile = [ttype,'_',etype,'_*.mat'];
            [fileSelected,pathSelected] = uigetfile(filterFile,'Select trials file', pathInitial);  %open a mat file

            if ischar(fileSelected)    
                % check input file for 'blocks' - if so, enable block
                % selection.
                app.isFileSelected = true;
                app.fileName = fileSelected;
                app.filePath = pathSelected;
                Y = load(fullfile(pathSelected, fileSelected));
                if any(contains(fieldnames(Y), 'blocks'))
                    app.fileNBlocks = length(Y.blocks);
                    % make a cell array with the numeric values 1:nBlocks, and
                    % the last value is 'None Selected'
                    C=[cellfun(@(x) sprintf('%d',x), num2cell(1:app.fileNBlocks),UniformOutput=false),{'None Selected'}];
                    [indx,tf]=listdlg(PromptString='Select a block',ListString=C,SelectionMode='single',ListSize=[150, 25*length(C)],InitialValue=length(C));
                    if tf
                        app.fileBlockIndex = indx;
                    end
                end

                % See if imageset info is contained

                if any(contains(fieldnames(Y), 'imagesetName')) && any(contains(fieldnames(Y), 'imagesetParamsFunc'))
                    app.isImagesetSelected = true;
                    app.imagesetName = Y.imagesetName;
                    app.imagesetParamsFunc = Y.imagesetParamsFunc;
                end
                    
            end
            updateFileBlocks(app);
            drawnow;
        end

        % Button pushed function: SelectImagesetButton
        function SelectImagesetButtonPushed(app, event)
            [pfuncSelected,pathSelected] = uigetfile('*.m','Select imageset loader', app.pathImgRoot);  %open a mat file

            if ischar(pfuncSelected)    
                [~,app.imagesetParamsFunc,~] = fileparts(pfuncSelected)
                c=split(strip(pathSelected,filesep),filesep);
                app.imagesetName = c{end};
            end
            updateFileBlocks(app);
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TesttypeButtonGroup = uibuttongroup(app.GridLayout);
            app.TesttypeButtonGroup.Title = 'Test type';
            app.ImageButton = uiradiobutton(app.TesttypeButtonGroup);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.GaborButton = uiradiobutton(app.TesttypeButtonGroup);
            app.GaborButton.Text = 'Gabor';
            app.ThresholdCheckBox = uicheckbox(app.GridLayout);
            app.ThresholdCheckBox.Text = 'Threshold?';
            app.SelectTrialsButton = uibutton(app.GridLayout, 'push');
            app.SelectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrialsPushed, true);
            app.SelectTrialsButton.Text = 'Select Trials';
            app.TrialsLabel = uilabel(app.GridLayout);
            app.TrialsLabel.Text = 'Trials:';
            app.TrialsFileLabel = uilabel(app.GridLayout);
            app.BlockLabel = uilabel(app.GridLayout);
            app.BlockLabel.Text = 'Block:';
            app.SubjectIDEditFieldLabel = uilabel(app.GridLayout);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.GridLayout, 'text');
            app.LocationLabel = uilabel(app.GridLayout);
            app.LocationLabel.Text = 'Location:';
            app.LocationDropDown = uidropdown(app.GridLayout);
            app.LocationDropDown.Items = {'Booth (test)', 'Booth (subj)', 'Desk'};
            app.LocationDropDown.Value = 'Booth (test)';
            app.BlockSelectedLabel = uilabel(app.GridLayout);
            app.BlockSelectedLabel.Text = 'Label3';
            app.ImagesetLabel = uilabel(app.GridLayout);
            app.ImagesetLabel.Text = 'Imageset';
            app.ImagesetNameLabel = uilabel(app.GridLayout);
            app.ImagesetNameLabel.Text = 'Label4';
            app.SelectImagesetButton = uibutton(app.GridLayout, 'push');
            app.SelectImagesetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesetButtonPushed, true);
            app.SelectImagesetButton.Text = 'Select Imageset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end